classdef PIT_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SubjectIDEditFieldLabel  matlab.ui.control.Label
        SubjectIDEditField       matlab.ui.control.EditField
        SessionButton            matlab.ui.container.ButtonGroup
        default2                 matlab.ui.control.RadioButton
        Day1Button               matlab.ui.control.RadioButton
        Day2Button               matlab.ui.control.RadioButton
        InterventionButton       matlab.ui.container.ButtonGroup
        default3                 matlab.ui.control.RadioButton
        NoneButton               matlab.ui.control.RadioButton
        StressButton             matlab.ui.control.RadioButton
        ControlButton_2          matlab.ui.control.RadioButton
        GroupButton              matlab.ui.container.ButtonGroup
        AUDButton                matlab.ui.control.RadioButton
        HCButton                 matlab.ui.control.RadioButton
        default4                 matlab.ui.control.RadioButton
        PITversionButton         matlab.ui.container.ButtonGroup
        default5                 matlab.ui.control.RadioButton
        ShellsButton             matlab.ui.control.RadioButton
        LeavesButton             matlab.ui.control.RadioButton
        RunExperimentButton      matlab.ui.control.Button
        SelectPITpartLabel       matlab.ui.control.Label
        SeletctPITpartDropDown   matlab.ui.control.DropDown
        SiteButtonGroup          matlab.ui.container.ButtonGroup
        BerlinButton             matlab.ui.control.RadioButton
        DresdenButton            matlab.ui.control.RadioButton
        ScanningButtonGroup      matlab.ui.container.ButtonGroup
        yesButton                matlab.ui.control.RadioButton
        noButton                 matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function BrowseButtonPushed(app, event)
            workdir = uigetdir; % get directory location
            app.UIFigure.Visible = 'off'; % workaround so that figure doesn't get sent to the background
            app.UIFigure.Visible = 'on';
            app.directory.Text = workdir;
            
            cd(workdir) % go to task directory
        end

        % Value changed function: SubjectIDEditField
        function SubjectIDEditFieldValueChanged(app, event)
            
            % return all subsequent values to default if subject ID is changed
            app.default2.Value = true;
            app.default3.Value = true;
            app.default4.Value = true;
            app.default5.Value = true;
            app.SeletctPITpartDropDown.Value='999';
            
            %% enable all subject-specific parameters if ID is changed
            app.Day1Button.Enable='on'; 
            app.Day2Button.Enable='on'; 
            app.NoneButton.Enable='on'; 
            app.StressButton.Enable='on'; 
            app.ControlButton_2.Enable='on'; 
            app.AUDButton.Enable='on'; 
            app.HCButton.Enable='on'; 
            app.ShellsButton.Enable='on'; 
            app.LeavesButton.Enable='on'; 
            app.BerlinButton.Enable='on'; 
            app.DresdenButton.Enable='on';
            app.yesButton.Enable='on'; 
            app.noButton.Enable='on'; 
            app.SeletctPITpartDropDown.Enable='on'; 

        end

        % Button pushed function: RunExperimentButton
        function RunExperimentButtonPushed(app, event)
            
            % get subjn
            subjn = app.SubjectIDEditField.Value;
       
            % get group
            if app.AUDButton.Value
                type='P';
            elseif app.HCButton.Value
                type='C';
            end

            % get experiment Part
            exppart=str2num(app.SeletctPITpartDropDown.Value);
            
            % get Study site
              if app.BerlinButton.Value
                  exploc='b';
              elseif app.DresdenButton.Value
                  exploc='d';
              end

            % get scanning
            
                if app.yesButton.Value
                    scanning=1;
                elseif app.noButton.Value
                    scanning=0;
                end
            
            % get Dresden Values
            if app.DresdenButton.Value
                
                intervention="none";
                session='1';
                PIT_version=1;
                
            elseif app.BerlinButton.Value
                
                % get session
                if app.Day1Button.Value
                    session='1';
                elseif app.Day2Button.Value
                    session='2';
                end
                
                % get intervention
                if app.NoneButton.Value
                    intervention="none";
                elseif app.StressButton.Value
                    intervention="stress";
                elseif app.ControlButton_2.Value
                    intervention="control";
                end
                
                % get PIT version
                if app.ShellsButton.Value
                    PIT_version=1;
                elseif app.LeavesButton.Value
                    PIT_version=2;
                end

            end
                
            
            % save everything in file guiparams, which will be loaded in
            % runexperiment to set subject-specific experimental parameters
            eval('save guiparams subjn session intervention PIT_version type exppart scanning exploc');
            
            %% disable all subject-specific parameters after Run Experiment
            % Button is pushed (this is just for conistency, i.e. changing
            % the values after exppart1 would not alter the parameters in the
            % experiment, as they are read in from exppart1)
            
            app.Day1Button.Enable='off'; 
            app.Day2Button.Enable='off'; 
            app.NoneButton.Enable='off'; 
            app.StressButton.Enable='off'; 
            app.ControlButton_2.Enable='off'; 
            app.AUDButton.Enable='off'; 
            app.HCButton.Enable='off'; 
            app.ShellsButton.Enable='off'; 
            app.LeavesButton.Enable='off'; 
            app.BerlinButton.Enable='off'; 
            app.DresdenButton.Enable='off';

            % run experiment
            runexperiment;
        end

        % Selection changed function: SiteButtonGroup
        function SiteButtonGroupSelectionChanged(app, event)
            selectedButton = app.SiteButtonGroup.SelectedObject;
            switch selectedButton
                case app.BerlinButton
                    app.PITversionButton.Visible='on';
                    app.SessionButton.Visible='on';
                    app.InterventionButton.Visible='on';
                case app.DresdenButton
                    app.PITversionButton.Visible='off';
                    app.SessionButton.Visible='off';
                    app.InterventionButton.Visible='off';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SubjectIDEditFieldLabel = uilabel(app.UIFigure);
            app.SubjectIDEditFieldLabel.Text = 'Subject ID';
            app.SubjectIDEditField = uieditfield(app.UIFigure, 'text');
            app.SubjectIDEditField.ValueChangedFcn = createCallbackFcn(app, @SubjectIDEditFieldValueChanged, true);
            app.SessionButton = uibuttongroup(app.UIFigure);
            app.SessionButton.Title = 'Session';
            app.default2 = uiradiobutton(app.SessionButton);
            app.default2.Enable = 'off';
            app.default2.Visible = 'off';
            app.default2.Text = 'initial';
            app.default2.Value = true;
            app.Day1Button = uiradiobutton(app.SessionButton);
            app.Day1Button.Text = 'Day 1';
            app.Day2Button = uiradiobutton(app.SessionButton);
            app.Day2Button.Text = 'Day 2';
            app.InterventionButton = uibuttongroup(app.UIFigure);
            app.InterventionButton.Title = 'Intervention';
            app.default3 = uiradiobutton(app.InterventionButton);
            app.default3.Enable = 'off';
            app.default3.Visible = 'off';
            app.default3.Text = 'initial';
            app.default3.Value = true;
            app.NoneButton = uiradiobutton(app.InterventionButton);
            app.NoneButton.Text = 'None';
            app.StressButton = uiradiobutton(app.InterventionButton);
            app.StressButton.Text = 'Stress';
            app.ControlButton_2 = uiradiobutton(app.InterventionButton);
            app.ControlButton_2.Text = 'Control';
            app.GroupButton = uibuttongroup(app.UIFigure);
            app.GroupButton.Title = 'Group';
            app.AUDButton = uiradiobutton(app.GroupButton);
            app.AUDButton.Text = 'AUD';
            app.HCButton = uiradiobutton(app.GroupButton);
            app.HCButton.Text = 'HC';
            app.default4 = uiradiobutton(app.GroupButton);
            app.default4.Enable = 'off';
            app.default4.Visible = 'off';
            app.default4.Text = 'initial';
            app.default4.Value = true;
            app.PITversionButton = uibuttongroup(app.UIFigure);
            app.PITversionButton.Title = 'PIT version';
            app.default5 = uiradiobutton(app.PITversionButton);
            app.default5.Visible = 'off';
            app.default5.Text = 'initial';
            app.default5.Value = true;
            app.ShellsButton = uiradiobutton(app.PITversionButton);
            app.ShellsButton.Text = 'Shells';
            app.LeavesButton = uiradiobutton(app.PITversionButton);
            app.LeavesButton.Text = 'Leaves';
            app.RunExperimentButton = uibutton(app.UIFigure, 'push');
            app.RunExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @RunExperimentButtonPushed, true);
            app.RunExperimentButton.Text = 'Run Experiment !';
            app.SelectPITpartLabel = uilabel(app.UIFigure);
            app.SelectPITpartLabel.Text = 'Select PIT part';
            app.SeletctPITpartDropDown = uidropdown(app.UIFigure);
            app.SeletctPITpartDropDown.Items = {'select', 'Part 1 (instrumental learning)', 'Part 2 (Pavlov)', 'Part 3 (PIT)', 'Part 4 (query trials)'};
            app.SeletctPITpartDropDown.ItemsData = {'999', '1', '2', '3', '4'};
            app.SeletctPITpartDropDown.Value = '999';
            app.SiteButtonGroup = uibuttongroup(app.UIFigure);
            app.SiteButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SiteButtonGroupSelectionChanged, true);
            app.SiteButtonGroup.Title = 'Site';
            app.BerlinButton = uiradiobutton(app.SiteButtonGroup);
            app.BerlinButton.Text = 'Berlin';
            app.BerlinButton.Value = true;
            app.DresdenButton = uiradiobutton(app.SiteButtonGroup);
            app.DresdenButton.Text = 'Dresden';
            app.ScanningButtonGroup = uibuttongroup(app.UIFigure);
            app.ScanningButtonGroup.Title = 'Scanning';
            app.yesButton = uiradiobutton(app.ScanningButtonGroup);
            app.yesButton.Text = 'yes';
            app.yesButton.Value = true;
            app.noButton = uiradiobutton(app.ScanningButtonGroup);
            app.noButton.Text = 'no';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PIT_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end